classdef BAIT_1_1_2022a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BiofilmArchitectureInferenceToolUIFigure  matlab.ui.Figure
        ViabilityBatchButton         matlab.ui.control.Button
        CHPorosityBatchButton        matlab.ui.control.Button
        ConnectivityBatchButton      matlab.ui.control.Button
        FluffinessBatchButton        matlab.ui.control.Button
        SurfaceAreaBatchButton       matlab.ui.control.Button
        ofObjectsBatchButton         matlab.ui.control.Button
        BiovolumeBatchButton         matlab.ui.control.Button
        ViabilityButton              matlab.ui.control.Button
        ConvexHullPorosityButton     matlab.ui.control.Button
        ConnectivityButton           matlab.ui.control.Button
        FluffinessButton             matlab.ui.control.Button
        BiovolumebyThresholdButton   matlab.ui.control.Button
        LogHistogramButton           matlab.ui.control.Button
        FiguresLabel                 matlab.ui.control.Label
        CalculateSelectedStackLabel  matlab.ui.control.Label
        AnalyzeAllButton             matlab.ui.control.Button
        ofObjectsButton              matlab.ui.control.Button
        SurfaceAreaButton            matlab.ui.control.Button
        ExportCSVButton              matlab.ui.control.Button
        BiovolumeButton              matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        ThresholdingButtonGroup      matlab.ui.container.ButtonGroup
        GreenChannelEditField        matlab.ui.control.NumericEditField
        GreenChannelEditFieldLabel   matlab.ui.control.Label
        RedChannelEditField          matlab.ui.control.NumericEditField
        RedChannelEditFieldLabel     matlab.ui.control.Label
        ManualButton                 matlab.ui.control.RadioButton
        ThresholdButton              matlab.ui.control.Button
        IterativeSelectionButton     matlab.ui.control.RadioButton
        OtsuButton                   matlab.ui.control.RadioButton
        BEMButton                    matlab.ui.control.RadioButton
        LogTextArea                  matlab.ui.control.TextArea
        LogTextAreaLabel             matlab.ui.control.Label
        ImageStackDropDown           matlab.ui.control.DropDown
        ImageStackDropDownLabel      matlab.ui.control.Label
        SelectFileButton             matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        fileName % input file name
        filePath % input file path
        dataStruct % struct containing image stacks
        arrayNames % image stack names
        imageStack % stack for analysis
        red_threshold_value
        green_threshold_value
        channel
        channels
        imageDimensions
    end

    methods (Access = private)

        function [green_threshold_value, vcolsgreen] = threshold_1channel(app,imageArray,method)
            
            %Thresholding
            app.LogTextArea.Value{end+1} = 'Calculating threshold...';
            vcolsgreen = [];
            % create a variable called 'gren' that represents the 
            %image
            if app.imageDimensions == '2-D'
                %handle the case where input matrix has three dimensions
                %(x,y,channel) 
                green = imageArray(:,:,app.channel);
            elseif  app.imageDimensions == '3-D'
                %handle the case where input matrix has four dimensions
                %(x,y,z,channel)
                green = imageArray(:,:,:,app.channel);

            end

            %if length(size(image5Darray)) == 5
                %green = image5Darray(:,:,:,1,app.channel);
            %elseif length(size(image5Darray)) == 3
                %green = image5Darray;
            %end
            
            if method(1) == 1
                app.LogTextArea.Value{end+1} = '   Using BEM';
                for i = 0:255
                    %%% Trimmed down biovolume code, also runs faster
                    T = green > i;       % Use a boolean filter directly
                    vcolsgreen = [vcolsgreen sum(T(:))];  % sum(A(:)) collapses all dimensions
                end
                
                threshold = 1:1:256;
                
                [fitgreen,gofgreen] = fit(transpose(threshold), transpose(vcolsgreen(1,:)), 'power2');
                
                range=(1:256);
                
                [fxgreen] = differentiate(fitgreen, range);
                
                percent_change_green=[transpose(1:255)];

                percent_change_green = [percent_change_green abs(diff(fxgreen)./fxgreen(1:end-1,:))];
                
                green_threshold = [];
                for i = 1:255
                    if percent_change_green(i,2) < .10
                        green_threshold = [green_threshold percent_change_green(i,:)];
                    end
                end
                
                green_threshold_value = green_threshold(1:1);

                app.LogTextArea.Value{end+1} = sprintf('The BEM threshold value is        %i\n',green_threshold_value);
            
            elseif method(2) == 1
                app.LogTextArea.Value{end+1} = '   Using Otsu''s method';
                green_threshold_value = round(graythresh(green)*256);
                
                app.LogTextArea.Value{end+1} = sprintf('The Otsu threshold value is        %i\n',green_threshold_value);
            elseif method(3) == 1
                app.LogTextArea.Value{end+1} = 'Using Iterative Selection';
                
                %IS Threshold
                
                histcountsgreen = histcounts(green, 256);
                grayscale = double(1:256);
                graygreen = [];
                sumgreengraybin = [];
                sumbingreen = [];
                avgfggreen = [];
                
                for i = 1:256 
                    graygreen = [graygreen grayscale(i)*histcountsgreen(i)];
                    
                    sumgreengraybin = [sumgreengraybin sum(graygreen(1:i))];
                    
                    sumbingreen = sum(histcountsgreen(1:i));
                    
                    avgfggreen = [avgfggreen sumgreengraybin(i)/sum(histcountsgreen(1:i))]; 
                end
                
                avgbggreen = [];
                toptgreen = [];
                difftoptgreen = [];
                
                for i = 1:255
                    avgbggreen = [avgbggreen ((sum(graygreen) - sumgreengraybin(i)))/(sum(histcountsgreen) - sum(histcountsgreen(1:i)))];
                    
                    toptgreen = [toptgreen (avgfggreen(i)+avgbggreen(i))/2];              
                    difftoptgreen = [difftoptgreen abs(toptgreen(i)-i)];
                end
                difftoptgreen = [1:255; difftoptgreen ];
                difftoptgreen = transpose(difftoptgreen);
                green_threshold_value = difftoptgreen(find(difftoptgreen(:,2)==min(difftoptgreen(:,2)),1));

                app.LogTextArea.Value{end+1} = sprintf('The iterative selection threshold value is        %i\n',green_threshold_value);
            elseif method(4) == 1
                app.LogTextArea.Value{end+1} = 'Using Manual Selection';
                if app.channel == 1
                    green_threshold_value = app.RedChannelEditField.Value;
                elseif app.channel == 2
                    green_threshold_value = app.GreenChannelEditField.Value;
                end
                app.LogTextArea.Value{end+1} = sprintf('The manually-selected threshold value is        %i\n',green_threshold_value);
            end
        end

        function [red_threshold_value, green_threshold_value, vcolsred, vcolsgreen] = threshold_2channel(app,imageArray,method)
            
            %Thresholding
            app.LogTextArea.Value{end+1} = 'Calculating threshold...';
            vcolsgreen = [];
            vcolsred = [];
            
            % added logic to check image dimensions from app properties
            if app.imageDimensions == '2-D'
                red = imageArray(:,:,1);
                green = imageArray(:,:,2);                  
            elseif app.imageDimensions == '3-D'
                red = imageArray(:,:,:,1);
                green = imageArray(:,:,:,2);                
            end
            
            %red = image5Darray(:,:,:,1,1);
            %green = image5Darray(:,:,:,1,2);
            
            if method(1) == 1
                app.LogTextArea.Value{end+1} = '   Using BEM';
                for i = 0:255
                    %%% Trimmed down biovolume code, also runs faster
                    T = green > i;       % Use a boolean filter directly
                    vcolsgreen = [vcolsgreen sum(T(:))];  % sum(A(:)) collapses all dimensions
                end
                
                for i = 0:255
                    %%% Trimmed down biovolume code, also runs faster
                    T = red > i;       % Use a boolean filter directly
                    vcolsred = [vcolsred sum(T(:))];  % sum(A(:)) collapses all dimensions
                end
                
                threshold = 1:1:256;
                
                [fitred,gofred] = fit(transpose(threshold), transpose(vcolsred(1,:)), 'power2');
                [fitgreen,gofgreen] = fit(transpose(threshold), transpose(vcolsgreen(1,:)), 'power2');
                
                range=(1:256);
                
                [fxgreen] = differentiate(fitgreen, range);
                [fxred] = differentiate(fitred, range);
                
                percent_change_red=[transpose(1:255)];
                percent_change_green=[transpose(1:255)];
                
                percent_change_red = [percent_change_red abs(diff(fxred)./fxred(1:end-1,:))];
                percent_change_green = [percent_change_green abs(diff(fxgreen)./fxgreen(1:end-1,:))];
                
                red_threshold = [];
                for i = 1:255
                    if percent_change_red(i,2) < .10
                        red_threshold = [red_threshold percent_change_red(i,:)];
                    end
                end
                
                red_threshold_value = red_threshold(1:1);
                
                green_threshold = [];
                for i = 1:255
                    if percent_change_green(i,2) < .10
                        green_threshold = [green_threshold percent_change_green(i,:)];
                    end
                end
                
                green_threshold_value = green_threshold(1:1);
              
                app.LogTextArea.Value{end+1} = sprintf('The red BEM threshold value is        %i\n',red_threshold_value);
                app.LogTextArea.Value{end+1} = sprintf('The green BEM threshold value is        %i\n',green_threshold_value);
            
            elseif method(2) == 1
                app.LogTextArea.Value{end+1} = '   Using Otsu''s method';
                red_threshold_value = round(graythresh(image5Darray(:,:,:,1,1))*256);
                green_threshold_value = round(graythresh(image5Darray(:,:,:,1,2))*256);
                
                app.LogTextArea.Value{end+1} = sprintf('The red Otsu threshold value is        %i\n',red_threshold_value);
                app.LogTextArea.Value{end+1} = sprintf('The green Otsu threshold value is        %i\n',green_threshold_value);
            elseif method(3) == 1
                app.LogTextArea.Value{end+1} = 'Using Iterative Selection';
                
                %IS Threshold
                red = image5Darray(:,:,:,1,1);
                green = image5Darray(:,:,:,1,2);
                
                histcountsred = histcounts(red,256);
                histcountsgreen = histcounts(green, 256);
                grayscale = double(1:256);
                grayred = [];
                graygreen = [];
                sumredgraybin = [];
                sumgreengraybin = [];
                sumbinred = [];
                sumbingreen = [];
                avgfgred = [];
                avgfggreen = [];
                
                for i = 1:256 
                    grayred = [grayred grayscale(i)*histcountsred(i)];
                    graygreen = [graygreen grayscale(i)*histcountsgreen(i)];
                    
                    sumredgraybin = [sumredgraybin sum(grayred(1:i))];
                    sumgreengraybin = [sumgreengraybin sum(graygreen(1:i))];
                    
                    sumbinred = sum(histcountsred(1:i));
                    sumbingreen = sum(histcountsgreen(1:i));
                    
                    avgfgred = [avgfgred sumredgraybin(i)/sum(histcountsred(1:i))];
                    avgfggreen = [avgfggreen sumgreengraybin(i)/sum(histcountsgreen(1:i))]; 
                end
                
                avgbgred = [];
                avgbggreen = [];
                toptred = [];
                toptgreen = [];
                difftoptred = [];
                difftoptgreen = [];
                
                for i = 1:255
                    avgbgred = [avgbgred ((sum(grayred) - sumredgraybin(i)))/(sum(histcountsred) - sum(histcountsred(1:i)))];
                    avgbggreen = [avgbggreen ((sum(graygreen) - sumgreengraybin(i)))/(sum(histcountsgreen) - sum(histcountsgreen(1:i)))];
                    
                    toptred = [toptred (avgfgred(i)+avgbgred(i))/2];
                    toptgreen = [toptgreen (avgfggreen(i)+avgbggreen(i))/2];
                    difftoptred = [difftoptred abs(toptred(i)-i)];                    
                    difftoptgreen = [difftoptgreen abs(toptgreen(i)-i)];
                end
                
                difftoptred = [1:255; difftoptred ];
                difftoptred = transpose(difftoptred);
                red_threshold_value = difftoptred(find(difftoptred(:,2)==min(difftoptred(:,2)),1));
                difftoptgreen = [1:255; difftoptgreen ];
                difftoptgreen = transpose(difftoptgreen);
                green_threshold_value = difftoptgreen(find(difftoptgreen(:,2)==min(difftoptgreen(:,2)),1));

                app.LogTextArea.Value{end+1} = sprintf('The red iterative selection threshold value is        %i\n',red_threshold_value);
                app.LogTextArea.Value{end+1} = sprintf('The green iterative selection threshold value is        %i\n',green_threshold_value);
            elseif method(4) == 1
                app.LogTextArea.Value{end+1} = 'Using Manual Selection';
                
                red_threshold_value = app.RedChannelEditField.Value;
                green_threshold_value = app.GreenChannelEditField.Value;
                
                app.LogTextArea.Value{end+1} = sprintf('The manually-selected red threshold value is        %i\n',red_threshold_value);
                app.LogTextArea.Value{end+1} = sprintf('The manually-selected green threshold value is        %i\n',green_threshold_value);
            end
        end

        function green_biovolume = biovolume_1channel(app, imageArray)
            %Biovolume
            % create a variable called 'green' that represents the 
            %image
            if app.imageDimensions == '2-D'
                %handle the case where input matrix has three dimensions
                %(x,y,channel) 
                green = imageArray(:,:,app.channel);
                matrix_size=size(green);
                G = zeros([matrix_size(1),matrix_size(2)]);
            elseif  app.imageDimensions == '3-D'
                %handle the case where input matrix has four dimensions
                %(x,y,z,channel)
                green = imageArray(:,:,:,app.channel);
                matrix_size=size(green);
                G = zeros([matrix_size(1),matrix_size(2),matrix_size(3)]);
            end
            
            %if length(size(image5Darray)) == 5
            %    image5Darray = image5Darray(:,:,:,1,app.channel);
            %elseif length(size(image5Darray)) == 3
            %    image5Darray = image5Darray;
            %end
            
            app.LogTextArea.Value{end+1} = 'Calculating Biovolume...';
            green_biovolume = [];
          
            G(green > app.green_threshold_value) = 1;
            G(green <= app.green_threshold_value) = 0;
            G=bwareaopen(G,2);

            green_biovolume = sum(sum(sum(G)));
            
            app.LogTextArea.Value{end+1} = sprintf('The number of voxels is        %i\n',green_biovolume);
            
        end
    
        function [red_biovolume, green_biovolume, merged_biovolume] = biovolume_2channel(app, imageArray)
            %Biovolume
            app.LogTextArea.Value{end+1} = 'Calculating Biovolume...';
            red_biovolume = [];
            green_biovolume = [];
            matrix_size=size(imageArray);
            % added logic to check image dimensions from app properties
            if app.imageDimensions == '2-D'
                red = imageArray(:,:,1);
                green = imageArray(:,:,2);
                R = zeros([matrix_size(1),matrix_size(2)]);
                G = zeros([matrix_size(1),matrix_size(2)]);
            elseif app.imageDimensions == '3-D'
                red = imageArray(:,:,:,1);
                green = imageArray(:,:,:,2);
                R = zeros([matrix_size(1),matrix_size(2),matrix_size(3)]);
                G = zeros([matrix_size(1),matrix_size(2),matrix_size(3)]);
            end

            R(red > app.red_threshold_value) = 1;
            R(red <= app.red_threshold_value) = 0;
            R=bwareaopen(R,2);

            G(green > app.green_threshold_value) = 1;
            G(green <= app.green_threshold_value) = 0;
            G=bwareaopen(G,2);
            
            C = or(G,R);
            
            red_biovolume = sum(sum(sum(R)));
            green_biovolume = sum(sum(sum(G)));
            merged_biovolume = sum(sum(sum(C)));


            app.LogTextArea.Value{end+1} = sprintf('The number of red voxels is        %i\n',red_biovolume);
            app.LogTextArea.Value{end+1} = sprintf('The number of green voxels is        %i\n',green_biovolume);
            app.LogTextArea.Value{end+1} = sprintf('The total number of voxels after collapsing red and green channels is        %i\n',merged_biovolume);
            
        end
        
        % TODO: change image5Darray to "imageArray"
        function green_surface_area = surface_area_1channel(app,imageArray)
            app.LogTextArea.Value{end+1} = 'Calculating Surface Area...';
            %Surface Area measurement

            if app.imageDimensions == '2-D'
                %handle the case where input matrix has three dimensions
                %(x,y,channel) 
                green = imageArray(:,:,app.channel);
                matrix_size=size(green);
                G = zeros([matrix_size(1),matrix_size(2)]);
            elseif  app.imageDimensions == '3-D'
                %handle the case where input matrix has four dimensions
                %(x,y,z,channel)
                green = imageArray(:,:,:,app.channel);
                matrix_size=size(green);
                G = zeros([matrix_size(1),matrix_size(2),matrix_size(3)]);
            end
            
            
            % Old dimension checking logic
            % TODO: Switch this out with the new dimension checking logic (see biovolume 1 channel function)
            %if length(size(image5Darray)) == 5
                %image5Darray = image5Darray(:,:,:,1,app.channel);
            %elseif length(size(image5Darray)) == 3
                %image5Darray = image5Darray;
            %end
            
            % TODO: Move this line up and change name of image array variable
             
            % TODO: replace references to image5Darray with "green" <- or whatever you choose
            % to name the variable that stores the subsetted image matrix

            G(green > app.green_threshold_value) = 1;
            G(green <= app.green_threshold_value) = 0;
            G=bwareaopen(G,2);
            
            cube6 = [0 0 0; 0 1 0; 0 0 0];
            cube6(:,:,2) = [0 1 0; 1 0 1; 0 1 0];
            cube6(:,:,3) = [0 0 0; 0 1 0; 0 0 0];

            Ginterfacial = convn(G, cube6, 'same');
    
            exposedsquaresG = 6 * G - Ginterfacial;
            exposedsquaresG(exposedsquaresG<0) = 0;
            green_surface_area = sum(sum(sum(exposedsquaresG)));

            app.LogTextArea.Value{end+1} = sprintf('The number of exposed interfacial surfaces of objects is        %i\n',green_surface_area);
        end
    
        % TODO: change image5Darray to "imageArray"
        function [red_surface_area,green_surface_area, merged_surface_area] = surface_area_2channel(app,imageArray)
            app.LogTextArea.Value{end+1} = 'Calculating Surface Area...';
            %Surface Area measurement

            matrix_size=size(imageArray);
            
            % TODO: add new two-channel dimension checking logic (see 2 channel biovolume function)
            if app.imageDimensions == '2-D'
                red = imageArray(:,:,1);
                green = imageArray(:,:,2);
                R = zeros([matrix_size(1),matrix_size(2)]);
                G = zeros([matrix_size(1),matrix_size(2)]);
            elseif app.imageDimensions == '3-D'
                red = imageArray(:,:,:,1);
                green = imageArray(:,:,:,2);
                R = zeros([matrix_size(1),matrix_size(2),matrix_size(3)]);
                G = zeros([matrix_size(1),matrix_size(2),matrix_size(3)]);
            end
            
            % move this line up and replace "image5Darray" with "imageArray"
            
            % TODO: move this line into the dimension checking if/else block (see 2 channel biovolume function)
          
            
            % TODO: replace references to image5Darray with "red" <- or whatever you choose
            % to name the variable that stores the subsetted image matrix for the red channel
            R(red > app.red_threshold_value) = 1;
            R(red <= app.red_threshold_value) = 0;
            R=bwareaopen(R,2);
            
             % TODO: move this line into the dimension checking if/else block (see 2 channel biovolume function)
       
            
            % TODO: replace references to image5Darray with "green" <- or whatever you choose
            % to name the variable that stores the subsetted image matrix for the green channel     
            G(green > app.green_threshold_value) = 1;
            G(green <= app.green_threshold_value) = 0;
            G=bwareaopen(G,2);
            
            C = or(G,R);
            
            cube6 = [0 0 0; 0 1 0; 0 0 0];
            cube6(:,:,2) = [0 1 0; 1 0 1; 0 1 0];
            cube6(:,:,3) = [0 0 0; 0 1 0; 0 0 0];
            
            Rinterfacial = convn(R, cube6, 'same');
            Ginterfacial = convn(G, cube6, 'same');
            Cinterfacial = convn(C, cube6, 'same');
            
            exposedsquaresR = 6 * R - Rinterfacial;
            exposedsquaresR(exposedsquaresR<0) = 0;
            red_surface_area = sum(sum(sum(exposedsquaresR)));
            
            exposedsquaresG = 6 * G - Ginterfacial;
            exposedsquaresG(exposedsquaresG<0) = 0;
            green_surface_area = sum(sum(sum(exposedsquaresG)));
            
            exposedsquaresC = 6 * C - Cinterfacial;
            exposedsquaresC(exposedsquaresC<0) = 0;
            merged_surface_area = sum(sum(sum(exposedsquaresC)));
            
            
            app.LogTextArea.Value{end+1} = sprintf('The number of exposed interfacial surfaces of red objects is        %i\n',red_surface_area);
            app.LogTextArea.Value{end+1} = sprintf('The number of exposed interfacial surfaces of green objects is        %i\n',green_surface_area);
            app.LogTextArea.Value{end+1} = sprintf('The number of exposed interfacial surfaces after collapsing red and green channels is        %i\n',merged_surface_area);
        end            

        function green_objects = objects_1channel(app,imageArray)
            if app.imageDimensions == '2-D'
                %handle the case where input matrix has three dimensions
                %(x,y,channel) 
                green = imageArray(:,:,app.channel);
                matrix_size=size(green);
                G = zeros([matrix_size(1),matrix_size(2)]);
            elseif  app.imageDimensions == '3-D'
                %handle the case where input matrix has four dimensions
                %(x,y,z,channel)
                green = imageArray(:,:,:,app.channel);
                matrix_size=size(green);
                G = zeros([matrix_size(1),matrix_size(2),matrix_size(3)]);
            end
            %if length(size(image5Darray)) == 5
                %image5Darray = image5Darray(:,:,:,1,app.channel);
            %elseif length(size(image5Darray)) == 3
                %image5Darray = image5Darray;
            %end
        
            app.LogTextArea.Value{end+1} = 'Calculating Total Number of Objects...';
            %Number of objects
            
 
            G(green > app.green_threshold_value) = 1;
            G(green <= app.green_threshold_value) = 0;
            G=bwareaopen(G,2);

            CCgreen = bwconncomp(G);
            numPixelsGreen = sort(transpose(cellfun(@numel,CCgreen.PixelIdxList)),'descend');
            green_objects = CCgreen.NumObjects;
            app.LogTextArea.Value{end+1} = sprintf('The number of objects is        %i\n',green_objects);
 
        end 
    
        function [red_objects,green_objects, merged_objects] = objects_2channel(app,imageArray)
        
            app.LogTextArea.Value{end+1} = 'Calculating Total Number of Objects...';
            %Number of objects

            matrix_size=size(imageArray);

            if app.imageDimensions == '2-D'
                red = imageArray(:,:,1);
                green = imageArray(:,:,2);
                R = zeros([matrix_size(1),matrix_size(2)]);
                G = zeros([matrix_size(1),matrix_size(2)]);
            elseif app.imageDimensions == '3-D'
                red = imageArray(:,:,:,1);
                green = imageArray(:,:,:,2);
                R = zeros([matrix_size(1),matrix_size(2),matrix_size(3)]);
                G = zeros([matrix_size(1),matrix_size(2),matrix_size(3)]);
            end
            
            
            R(red > app.red_threshold_value) = 1;
            R(red <= app.red_threshold_value) = 0;
            R=bwareaopen(R,2);
            
          
            G(green > app.green_threshold_value) = 1;
            G(green <= app.green_threshold_value) = 0;
            G=bwareaopen(G,2);

            C = or(G,R);
            
            CCred = bwconncomp(R);
            numPixelsRed = sort(transpose(cellfun(@numel,CCred.PixelIdxList)),'descend');
            red_objects = CCred.NumObjects;

            CCgreen = bwconncomp(G);
            numPixelsGreen = sort(transpose(cellfun(@numel,CCgreen.PixelIdxList)),'descend');
            green_objects = CCgreen.NumObjects
            
            CCmerged = bwconncomp(C);
            numPixelsMerged = sort(transpose(cellfun(@numel,CCmerged.PixelIdxList)),'descend');
            merged_objects = CCmerged.NumObjects
            
            app.LogTextArea.Value{end+1} = sprintf('The number of red objects is        %i\n',red_objects);
            app.LogTextArea.Value{end+1} = sprintf('The number of green objects is        %i\n',green_objects);
            app.LogTextArea.Value{end+1} = sprintf('The number of objects after collapsing red and green channels is        %i\n',merged_objects);
            
 
        end   
    
        function chporositygreen = porosity_1channel(app,imageArray)
            %if length(size(image5Darray)) == 5
                %image5Darray = image5Darray(:,:,:,1,app.channel);
            %elseif length(size(image5Darray)) == 3
                %image5Darray = image5Darray;
            %end


            if app.imageDimensions == '2-D'
                %handle the case where input matrix has three dimensions
                %(x,y,channel) 
                green = imageArray(:,:,app.channel);
                matrix_size=size(green);
                G = zeros([matrix_size(1),matrix_size(2)]);
            elseif  app.imageDimensions == '3-D'
                %handle the case where input matrix has four dimensions
                %(x,y,z,channel)
                green = imageArray(:,:,:,app.channel);
                matrix_size=size(green);
                G = zeros([matrix_size(1),matrix_size(2),matrix_size(3)]);
            end
            
            function [copl,rnk] = iscoplanar(X,Y,Z,tolerance)

            switch nargin
	    case 0
		error('Requires at least one input argument.');
	    case 1
		if size(X,2) > 1
			allPoints = X;
			tolerance = [];
		else
			error('Invalid input.')
		end
	    case 2
		allPoints = X;
		tolerance = Y;
	    case 3
		if all([isvector(X),isvector(Y),isvector(Z)])
			allPoints = [X(:) Y(:) Z(:)];
			tolerance = [];
		else
			error('Invalid input.')
		end
	    case 4
		if all([isvector(X), isvector(Y), isvector(Z)])
			allPoints = [X(:) Y(:) Z(:)];
		else
			error('Invalid input.');
		end
	    otherwise
		error('Too many input arguments.');
            end

            if size(X,1) <= 3
	    disp('Three or fewer points are necessarily coplanar.');
	    copl = 1;
	    if nargout > 1
		rnk = calculateRank(allPoints, tolerance);
	    end
	    return;
            end
            rnk = calculateRank(allPoints, tolerance);
            % Dimensionality of R (space) is size(allPoints,2):
            dimensionality = size(allPoints,2);
            copl = rnk <= dimensionality - 1;

            end

            function rnk = calculateRank(V,tol)
                if isempty(tol)
    	           rnk = rank(bsxfun(@minus,V(2:end,:),V(1,:)));
                else
    	           rnk = rank(bsxfun(@minus,V(2:end,:),V(1,:)),tol);
                end
            end    
            
            app.LogTextArea.Value{end+1} = 'Calculating Convex Hull Porosity...'; 
            %Convex Hull Porosity


            G(green > app.green_threshold_value) = 1;
            G(green <= app.green_threshold_value) = 0;
            G = bwareaopen(G,100);

            CCgreen = bwconncomp(G);

            allVgreen = [];
            for i = 1:size(CCgreen.PixelIdxList,2)
                CCgreen2 = zeros(size(G));
                CCgreen2(CCgreen.PixelIdxList{i}) = 1;
                Dgreen = squeeze(CCgreen2);
                idxgreen = logical(Dgreen);
                valsgreen = Dgreen(idxgreen);
                indgreen = find(idxgreen);
                [xgreen,ygreen,zgreen] = ind2sub(size(Dgreen),indgreen);
                if iscoplanar(xgreen,ygreen,zgreen) == 0;
                    [Kgreen,Vgreen] = convhull(xgreen,ygreen,zgreen,'simplify', true);
                    allVgreen = [allVgreen Vgreen];
                end
            end

            chporositygreen = (sum(allVgreen) - sum(sum(sum(G))))/sum(allVgreen);

            app.LogTextArea.Value{end+1} = sprintf('The convex hull porosity is        %i\n',chporositygreen);

        end
        
        function [chporosityred, chporositygreen, chporositymerged] = porosity_2channel(app,imageArray)
            
            function [copl,rnk] = iscoplanar(X,Y,Z,tolerance)

            switch nargin
	    case 0
		error('Requires at least one input argument.');
	    case 1
		if size(X,2) > 1
			allPoints = X;
			tolerance = [];
		else
			error('Invalid input.')
		end
	    case 2
		allPoints = X;
		tolerance = Y;
	    case 3
		if all([isvector(X),isvector(Y),isvector(Z)])
			allPoints = [X(:) Y(:) Z(:)];
			tolerance = [];
		else
			error('Invalid input.')
		end
	    case 4
		if all([isvector(X), isvector(Y), isvector(Z)])
			allPoints = [X(:) Y(:) Z(:)];
		else
			error('Invalid input.');
		end
	    otherwise
		error('Too many input arguments.');
            end

            if size(X,1) <= 3
	    disp('Three or fewer points are necessarily coplanar.');
	    copl = 1;
	    if nargout > 1
		rnk = calculateRank(allPoints, tolerance);
	    end
	    return;
            end
            rnk = calculateRank(allPoints, tolerance);
            % Dimensionality of R (space) is size(allPoints,2):
            dimensionality = size(allPoints,2);
            copl = rnk <= dimensionality - 1;

            end

            function rnk = calculateRank(V,tol)
            if isempty(tol)
	    rnk = rank(bsxfun(@minus,V(2:end,:),V(1,:)));
            else
	    rnk = rank(bsxfun(@minus,V(2:end,:),V(1,:)),tol);
            end
            end    
            
            app.LogTextArea.Value{end+1} = 'Calculating Convex Hull Porosity...'; 
            %Convex Hull Porosity
            matrix_size=size(imageArray);
            % added logic to check image dimensions from app properties
            if app.imageDimensions == '2-D'
                red = imageArray(:,:,1);
                green = imageArray(:,:,2);
                R = zeros([matrix_size(1),matrix_size(2)]);
                G = zeros([matrix_size(1),matrix_size(2)]);
            elseif app.imageDimensions == '3-D'
                red = imageArray(:,:,:,1);
                green = imageArray(:,:,:,2);
                R = zeros([matrix_size(1),matrix_size(2),matrix_size(3)]);
                G = zeros([matrix_size(1),matrix_size(2),matrix_size(3)]);
            end
          

            R(red > app.red_threshold_value) = 1;
            R(red <= app.red_threshold_value) = 0;
            R = bwareaopen(R,100);

            CCred = bwconncomp(R);

            allVred = [];
            for i = 1:size(CCred.PixelIdxList,2)
                CCred2 = zeros(size(R));
                CCred2(CCred.PixelIdxList{i}) = 1;
                Dred = squeeze(CCred2);
                idxred = logical(Dred);
                valsred = Dred(idxred);
                indred = find(idxred);
                [xred,yred,zred] = ind2sub(size(Dred),indred);
                if iscoplanar(xred,yred,zred) == 0;
                    [Kred,Vred] = convhull(xred,yred,zred,'simplify', true);
                    allVred = [allVred Vred];
                end
            end

chporosityred = (sum(allVred) - sum(sum(sum(R))))/sum(allVred);

       
            G(green > app.green_threshold_value) = 1;
            G(green <= app.green_threshold_value) = 0;
            G = bwareaopen(G,100);

            CCgreen = bwconncomp(G);

            allVgreen = [];
            for i = 1:size(CCgreen.PixelIdxList,2)
                CCgreen2 = zeros(size(G));
                CCgreen2(CCgreen.PixelIdxList{i}) = 1;
                Dgreen = squeeze(CCgreen2);
                idxgreen = logical(Dgreen);
                valsgreen = Dgreen(idxgreen);
                indgreen = find(idxgreen);
                [xgreen,ygreen,zgreen] = ind2sub(size(Dgreen),indgreen);
                if iscoplanar(xgreen,ygreen,zgreen) == 0;
                    [Kgreen,Vgreen] = convhull(xgreen,ygreen,zgreen,'simplify', true);
                    allVgreen = [allVgreen Vgreen];
                end
            end

chporositygreen = (sum(allVgreen) - sum(sum(sum(G))))/sum(allVgreen);

            C = or(G,R);
            
            CCmerged = bwconncomp(C);
            
            allVmerged = [];
            for i = 1:size(CCmerged.PixelIdxList,2)
                CCmerged2 = zeros(size(C));
                CCmerged2(CCmerged.PixelIdxList{i}) = 1;
                Dmerged = squeeze(CCmerged2);
                idxmerged = logical(Dmerged);
                valsmerged = Dmerged(idxmerged);
                indmerged = find(idxmerged);
                [xmerged,ymerged,zmerged] = ind2sub(size(Dmerged),indmerged);
                if iscoplanar(xmerged,ymerged,zmerged) == 0;
                        [Kmerged,Vmerged] = convhull(xmerged,ymerged,zmerged,'simplify',true);
                        allVmerged = [allVmerged Vmerged];
                end
            end             

chporositymerged = (sum(allVmerged) - sum(sum(sum(C))))/sum(allVmerged);

            app.LogTextArea.Value{end+1} = sprintf('The convex hull porosity of the red channel is        %i\n',chporosityred);
            app.LogTextArea.Value{end+1} = sprintf('The convex hull porosity of the green channel is        %i\n',chporositygreen);
            app.LogTextArea.Value{end+1} = sprintf('The convex hull porosity of the collapsed red and green channel is        %i\n',chporositymerged);

end
        
        
        function log_histogram(app,imageArray)
            if app.channels == 1
                if app.imageDimensions == '2-D'
                    %handle the case where input matrix has three dimensions
                    %(x,y,channel) 
                    green = imageArray(:,:,2);
                elseif  app.imageDimensions == '3-D'
                    %handle the case where input matrix has four dimensions
                    %(x,y,z,channel)
                    green = imageArray(:,:,:,2);
                end

                cla(app.UIAxes);
                cla(app.UIAxes2);
                
                app.LogTextArea.Value{end+1} = 'Projecting log histogram';
                %Histogram Figure
                
                threshold = 1:1:256;
                
                Green1 = green;
                histcountsgreen = histcounts(Green1,256);
                
                
                plot(threshold,log(histcountsgreen),'green','LineWidth',2.5, 'Parent', app.UIAxes)
                app.UIAxes.XLabel.String = 'Grey Level';
                app.UIAxes.YLabel.String = 'Log Count';
                app.UIAxes.Title.String = 'Log Voxel Count by Grey Level Histogram';
                app.UIAxes.XLim = ([0 256]);
                legend(app.UIAxes, 'Log Voxel Count by Grey Level Curve');
            elseif app.channels == 2
                if app.imageDimensions == '2-D'
                    %handle the case where input matrix has three dimensions
                    %(x,y,channel) 
                    green = imageArray(:,:,2);
                    red = imageArray(:,:,1);
                elseif  app.imageDimensions == '3-D'
                    %handle the case where input matrix has four dimensions
                    %(x,y,z,channel)
                    green = imageArray(:,:,:,2);
                    red = imageArray(:,:,:,1);
                end
                cla(app.UIAxes);
                cla(app.UIAxes2);
                
                app.LogTextArea.Value{end+1} = 'Projecting log histogram';
                %Histogram Figure
                
                threshold = 1:1:256;
                
                Red1 = red;
                histcountsred = histcounts(Red1,256);
                
                
                Green1 = green;
                histcountsgreen = histcounts(Green1,256);
                
                
                plot(threshold,log(histcountsgreen),'green','LineWidth',2.5, 'Parent', app.UIAxes)
                app.UIAxes.XLabel.String = 'Grey Level';
                app.UIAxes.YLabel.String = 'Log Count';
                app.UIAxes.Title.String = 'Log Voxel Count by Grey Level Histogram (Green Channel)';
                app.UIAxes.XLim = ([0 256]);
                legend(app.UIAxes, 'Log Voxel Count by Grey Level Curve');
                
                
                plot(threshold,log(histcountsred),'red','LineWidth',2.5, 'Parent', app.UIAxes2)
                app.UIAxes2.XLabel.String = 'Grey Level';
                app.UIAxes2.YLabel.String = 'Log Count';
                app.UIAxes2.Title.String = 'Log Voxel Count by Grey Level Histogram (Red Channel)';
                app.UIAxes2.XLim = ([0 256]);
                legend(app.UIAxes2, 'Log Voxel Count by Grey Level Curve');
            end
        end
    
        function biovolume_by_threshold_1channel(app, imageArray)
         % create a variable called 'green' that represents the 
            %image
            if app.imageDimensions == '2-D'
                %handle the case where input matrix has three dimensions
                %(x,y,channel) 
                green = imageArray(:,:,app.channel);
                matrix_size=size(green);
                G = zeros([matrix_size(1),matrix_size(2)]);
            elseif  app.imageDimensions == '3-D'
                %handle the case where input matrix has four dimensions
                %(x,y,z,channel)
                green = imageArray(:,:,:,app.channel);
                matrix_size=size(green);
                G = zeros([matrix_size(1),matrix_size(2),matrix_size(3)]);
            end
            cla(app.UIAxes);
            cla(app.UIAxes2);
            
            app.LogTextArea.Value{end+1} = 'Creating biovolume by threshold curve';
            %Biovolume by threshold curve
            
            vcolsgreen = [];
            Green1 = green;
            
            for i=0:255
                T = Green1 > i;
                vcolsgreen = [vcolsgreen sum(T(:))];
            end
            
            threshold = 1:1:256;
            
            
            plot(threshold,vcolsgreen(1,:),'green','LineWidth',2.5, 'Parent', app.UIAxes)
            app.UIAxes.XLabel.String = 'Threshold';
            app.UIAxes.YLabel.String = 'Biovolume';
            app.UIAxes.Title.String = 'Biovolume by Threshold';
            app.UIAxes.XLim = ([0 256]);
            hold(app.UIAxes,'on');
            
            
            [fitgreen,gofgreen] = fit(transpose(threshold), transpose(vcolsgreen(1,:)), 'power2');
            greenY = fitgreen(threshold);
            plot(threshold ,greenY,'Parent',app.UIAxes)
            legend(app.UIAxes, 'Biovolume by Threshold Data','Fitted Model')
            app.LogTextArea.Value{end+1} = sprintf('The correlation coefficient of the fitted biovolume curve to data is          %.4f', gofgreen.rsquare);
            app.LogTextArea.Value{end+1} = sprintf('The adjusted correlation coefficient of the fitted biovolume curve to data is          %.4f', gofgreen.adjrsquare);

        end
    
        function biovolume_by_threshold_2channel(app, imageArray)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            
            app.LogTextArea.Value{end+1} = 'Creating biovolume by threshold curve';
            %Biovolume by threshold curve

              if app.imageDimensions == '2-D'
                    %handle the case where input matrix has three dimensions
                    %(x,y,channel) 
                    green = imageArray(:,:,2);
                    red = imageArray(:,:,1);
              elseif  app.imageDimensions == '3-D'
                    %handle the case where input matrix has four dimensions
                    %(x,y,z,channel)
                    green = imageArray(:,:,:,2);
                    red = imageArray(:,:,:,1);
              end
            
            vcolsgreen = [];
            vcolsred = [];
            Green1 = green;
            Red1 = red;
            
            for i=0:255
                T = Green1 > i;
                vcolsgreen = [vcolsgreen sum(T(:))];
            end
            
            for i=0:255
                R = Red1 > i;
                vcolsred = [vcolsred sum(R(:))];
            end
            
            threshold = 1:1:256;
            
            
            plot(threshold,vcolsgreen(1,:),'green','LineWidth',2.5, 'Parent', app.UIAxes)
            app.UIAxes.XLabel.String = 'Threshold';
            app.UIAxes.YLabel.String = 'Green Biovolume';
            app.UIAxes.Title.String = 'Green biovolume by Threshold (Green Channel)';
            app.UIAxes.XLim = ([0 256]);
            hold(app.UIAxes,'on');
            
            
            plot(threshold,vcolsred(1,:),'red','LineWidth',2.5, 'Parent', app.UIAxes2)
            app.UIAxes2.XLabel.String = 'Threshold';
            app.UIAxes2.YLabel.String = 'Red Biovolume';
            app.UIAxes2.Title.String = 'Red biovolume by Threshold (Red Channel)';
            app.UIAxes2.XLim = ([0 256]);
            hold(app.UIAxes2,'on');
            
            
            [fitgreen,gofgreen] = fit(transpose(threshold), transpose(vcolsgreen(1,:)), 'power2');
            greenY = fitgreen(threshold);
            plot(threshold ,greenY,'Parent',app.UIAxes)
            legend(app.UIAxes, 'Green Biovolume by Threshold Data','Fitted Model')
            app.LogTextArea.Value{end+1} = sprintf('The correlation coefficient of the fitted green biovolume curve to data is          %.4f', gofgreen.rsquare);
            app.LogTextArea.Value{end+1} = sprintf('The adjusted correlation coefficient of the fitted green biovolume curve to data is          %.4f', gofgreen.adjrsquare);
            
            [fitred,gofred] = fit(transpose(threshold), transpose(vcolsred(1,:)), 'power2');
            redY = fitred(threshold);
            plot(threshold ,redY,'Parent',app.UIAxes2)
            legend(app.UIAxes2, 'Red Biovolume by Threshold Data','Fitted Model')
            app.LogTextArea.Value{end+1} = sprintf('The correlation coefficient of the fitted red biovolume curve to data is          %.4f', gofred.rsquare);
            app.LogTextArea.Value{end+1} = sprintf('The adjusted correlation coefficient of the fitted red biovolume curve to data is          %.4f', gofred.adjrsquare);
            
        end
            
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [app.fileName,app.filePath] = uigetfile('*.mat','Select Matlab Data File');
            app.dataStruct = load(fullfile(app.filePath,app.fileName));
            app.arrayNames = fieldnames(app.dataStruct);
            app.ImageStackDropDown.Items = app.arrayNames;
            app.ImageStackDropDownValueChanged();
            dim = size(app.imageStack);
            
            if (length(dim) > 4)
                error('Input Error: Image matrix has too many dimensions')
            end
            
            fig = uifigure;
            app.imageDimensions = uiconfirm(fig,'Image Dimensions','Select Dimensions','Options',{'2-D','3-D'})
            delete(fig);
            
            % make sure the image file has the right dimensions
            % if 2-D, should be a 3-D matrix
            if (app.imageDimensions == '2-D')
                if length(dim) ~= 3
                   error('Input Error: Image matrix dimensions incompatible with selection "2-D"');
                end
            end
            % if 3-D, should be a 4-D matrix
            if (app.imageDimensions == '3-D')
                if length(dim) ~= 4
                   error('Input Error: Image matrix dimensions incompatible with selection "3-D"');
                end
            end
            % raise an error if I get a 5-D matrix
            
            if (dim(end) == 2)
                f = uifigure;
                selection = uiconfirm(f,'Two channels detected, choose which one(s) to evaluate','Select a Channel','Options',{'Channel 1','Channel 2','Both','Cancel'},'DefaultOption',3,'CancelOption',4);
                delete(f);

                if strcmp(selection,'Cancel') == 0
                    if strcmp(selection,'Both')
                        app.channels = 2;
                    elseif strcmp(selection,'Channel 1')
                        app.channels = 1;
                        app.channel = 1;
                    elseif strcmp(selection, 'Channel 2')
                        app.channels = 1;
                        app.channel = 2;
                    end
                end
            elseif length(dim) == 3
                app.channels = 1;
            end
            set(app.ThresholdButton,'enable','on');
            set(app.AnalyzeAllButton,'enable','on');
            set(app.BiovolumeBatchButton,'enable','on');
            set(app.ofObjectsBatchButton,'enable','on');
            set(app.SurfaceAreaBatchButton,'enable','on');
            set(app.FluffinessBatchButton,'enable','on');
            set(app.ConnectivityBatchButton,'enable','on');
            set(app.CHPorosityBatchButton,'enable','on');
            if app.channels == 1
                set(app.ViabilityBatchButton,'enable','off');
            elseif app.channels == 2
                set(app.ViabilityBatchButton,'enable','on');
            end
        end

        % Button pushed function: ThresholdButton
        function ThresholdButtonPushed(app, event)
            app.LogTextArea.Value = {''};
            %cla(app.RedUIAxes);
            %cla(app.GreenUIAxes);
            method = [app.BEMButton.Value,app.OtsuButton.Value,app.IterativeSelectionButton.Value,app.ManualButton.Value];
            
            if app.channels == 1
                [thresh, vcols] = app.threshold_1channel(app.imageStack,method);
                app.green_threshold_value = thresh;
                set(app.ViabilityButton,'enable','off');
            elseif app.channels == 2
                [redThresh, greenThresh, vcolsred, vcolsgreen] = app.threshold_2channel(app.imageStack, method);
                app.red_threshold_value = redThresh;
                app.green_threshold_value = greenThresh;
                set(app.ViabilityButton,'enable','on');
            end
            
            set(app.BiovolumeButton,'enable','on');
            set(app.SurfaceAreaButton,'enable','on');
            set(app.ofObjectsButton,'enable','on');
            set(app.FluffinessButton,'enable','on');
            set(app.ConnectivityButton,'enable','on');
            set(app.ConvexHullPorosityButton,'enable','on');
        end

        % Value changed function: ImageStackDropDown
        function ImageStackDropDownValueChanged(app, event)
            value = app.ImageStackDropDown.Value;
            app.imageStack = app.dataStruct.(value);
            
            set(app.BiovolumeButton,'enable','off');
            set(app.SurfaceAreaButton,'enable','off');
            set(app.ofObjectsButton,'enable','off');
            set(app.FluffinessButton,'enable','off');
            set(app.ConnectivityButton,'enable','off');
            set(app.ConvexHullPorosityButton,'enable','off');
            set(app.ViabilityButton,'enable','off');
            set(app.LogHistogramButton, 'enable', 'on');
            set(app.BiovolumebyThresholdButton, 'enable', 'on');
        end

        % Button pushed function: BiovolumeButton
        function BiovolumeButtonPushed(app, event)
            if app.channels == 1
                biovolume = app.biovolume_1channel(app.imageStack);
            elseif app.channels == 2
                [red_biovolume, green_biovolume,merged_biovolume] = app.biovolume_2channel(app.imageStack);
            end
        end

        % Button pushed function: ExportCSVButton
        function ExportCSVButtonPushed(app, event)
            out = cell2table(app.UITable.Data);
            outName = 'summary.csv';
            app.LogTextArea.Value{end+1} = ['Exporting to:',' ',outName];
            writetable(out,outName,'WriteVariableNames',false);
        end

        % Button pushed function: SurfaceAreaButton
        function SurfaceAreaButtonPushed(app, event)
            if app.channels == 1
                surace_area = app.surface_area_1channel(app.imageStack);
            elseif app.channels == 2
                [red_surface_area, green_surface_area,merged_surface_area] = app.surface_area_2channel(app.imageStack);
            end
        end

        % Button pushed function: ofObjectsButton
        function ofObjectsButtonPushed(app, event)
            if app.channels == 1
                objects = app.objects_1channel(app.imageStack);
            elseif app.channels == 2
                [red_objects,green_objects,merged_objects] = app.objects_2channel(app.imageStack);
            end
        end

        % Button pushed function: AnalyzeAllButton
        function AnalyzeAllButtonPushed(app, event)
            set(app.ExportCSVButton,'enable','on');
            
            if app.channels == 1
                app.UITable.Data = {'Image Stack';
                                    'Threshold';
                                    'Biovolume';
                                    'Total number of objects';
                                    'Exposed surface voxels';
                                    'Fluffiness (SA/BV)';
                                    'Connectivity (Avg. BV per object)';
                                    'Convex hull porosity';
                                    };
                
                for i = 1:length(app.arrayNames)
                
                fields = fieldnames(app.dataStruct);
                
                method = [app.BEMButton.Value,app.OtsuButton.Value,app.IterativeSelectionButton.Value,app.ManualButton.Value];
                [greenThresh, vcolsgreen] = app.threshold_1channel(app.dataStruct.(fields{i}), method);
                app.green_threshold_value = greenThresh;
                
                green_biovolume = app.biovolume_1channel(app.dataStruct.(fields{i}));
                green_surface_area = app.surface_area_1channel(app.dataStruct.(fields{i}));
                green_objects = app.objects_1channel(app.dataStruct.(fields{i}));
                chporositygreen = app.porosity_1channel(app.dataStruct.(fields{i}));
                
                app.UITable.Data = horzcat(app.UITable.Data, { , fields{i};
                                                               , app.green_threshold_value;
                                                               , green_biovolume;
                                                               , green_objects;
                                                               , green_surface_area;
                                                               , green_surface_area/green_biovolume;
                                                               , green_biovolume/green_objects;
                                                               , chporositygreen;
                                                               });
                                  
                end
                set(app.ViabilityButton,'enable','off');
            elseif app.channels == 2
                app.UITable.Data = {'Image Stack';
                                    'Red channel threshold';
                                    'Green channel threshold';
                                    'Red channel biovolume';
                                    'Green channel biovolume';
                                    'Collapsed channel biovolume';
                                    'Red channel total number of objects';
                                    'Green channel total number of objects';
                                    'Collapsed channel total number of objects';
                                    'Red channel exposed surface voxels';
                                    'Green channel exposed surface voxels';
                                    'Collapsed channel exposed surface voxels';
                                    'Red channel fluffiness (SA/BV)';
                                    'Green channel fluffiness (SA/BV)';
                                    'Collapsed channel fluffiness(SA/BV)';
                                    'Red channel connectivity (Avg. BV per object)';
                                    'Green channel connectivity (Avg. BV per object)';
                                    'Collapsed channel connectivity (Avg. BV per object)';
                                    'Red channel convex hull porosity';
                                    'Green channel convex hull porosity';
                                    'Collapsed channel convex hull porosity';
                                    'Viability (Proportion of voxels that are green)';
                                    };
                
                for i = 1:length(app.arrayNames)
                
                fields = fieldnames(app.dataStruct);
                
                method = [app.BEMButton.Value,app.OtsuButton.Value,app.IterativeSelectionButton.Value,app.ManualButton.Value];
                [redThresh, greenThresh, vcolsred, vcolsgreen] = app.threshold_2channel(app.dataStruct.(fields{i}), method);
                app.red_threshold_value = redThresh;
                app.green_threshold_value = greenThresh;
                
                [red_biovolume, green_biovolume, merged_biovolume] = app.biovolume_2channel(app.dataStruct.(fields{i}));
                [red_surface_area, green_surface_area, merged_surface_area] = app.surface_area_2channel(app.dataStruct.(fields{i}));
                [red_objects,green_objects, merged_objects] = app.objects_2channel(app.dataStruct.(fields{i}));
                [chporosityred,chporositygreen, chporositymerged] = app.porosity_2channel(app.dataStruct.(fields{i}));
                
                app.UITable.Data = horzcat(app.UITable.Data, { , fields{i};
                                                               , app.red_threshold_value;
                                                               , app.green_threshold_value;
                                                               , red_biovolume;
                                                               , green_biovolume;
                                                               , merged_biovolume;
                                                               , red_objects;
                                                               , green_objects;
                                                               , merged_objects;
                                                               , red_surface_area;
                                                               , green_surface_area;
                                                               , merged_surface_area;
                                                               , red_surface_area/red_biovolume;
                                                               , green_surface_area/green_biovolume;
                                                               , merged_surface_area/merged_biovolume;
                                                               , red_biovolume/red_objects;
                                                               , green_biovolume/green_objects;
                                                               , merged_biovolume/merged_objects;
                                                               , chporosityred;
                                                               , chporositygreen;
                                                               , chporositymerged;
                                                               , green_biovolume/(red_biovolume+green_biovolume);
                                                               });
                                  
                end
                set(app.ViabilityButton,'enable','on');
            end
            set(app.BiovolumeButton,'enable','on');
            set(app.SurfaceAreaButton,'enable','on');
            set(app.ofObjectsButton,'enable','on');
            set(app.FluffinessButton,'enable','on');
            set(app.ConnectivityButton,'enable','on');
            set(app.ConvexHullPorosityButton,'enable','on');
        end

        % Selection changed function: ThresholdingButtonGroup
        function ThresholdingButtonGroupSelectionChanged(app, event)
            selectedButton = app.ThresholdingButtonGroup.SelectedObject;
            set(app.BiovolumeButton,'enable','off');
            set(app.SurfaceAreaButton,'enable','off');
            set(app.ofObjectsButton,'enable','off');
            set(app.FluffinessButton,'enable','off');
            set(app.ConnectivityButton,'enable','off');
            set(app.ConvexHullPorosityButton,'enable','off');
            set(app.ViabilityButton,'enable','off');
            
            if selectedButton == app.ManualButton
                if app.channels == 1
                    if app.channel == 1
                        set(app.RedChannelEditField,'enable','on');
                        set(app.GreenChannelEditField,'enable','off'); 
                    elseif app.channel == 2
                        set(app.RedChannelEditField,'enable','off');
                        set(app.GreenChannelEditField,'enable','on');
                    end
                elseif app.channels == 2
                    set(app.RedChannelEditField,'enable','on');
                    set(app.GreenChannelEditField,'enable','on');                
                end
            else
                set(app.RedChannelEditField,'enable','off');
                set(app.GreenChannelEditField,'enable','off');                
            end
                
        end

        % Button pushed function: LogHistogramButton
        function LogHistogramButtonPushed(app, event)
            app.log_histogram(app.imageStack);
        end

        % Button pushed function: BiovolumebyThresholdButton
        function BiovolumebyThresholdButtonPushed(app, event)
            if app.channels == 1
                app.biovolume_by_threshold_1channel(app.imageStack);
            elseif app.channels == 2
                app.biovolume_by_threshold_2channel(app.imageStack);
            end
        end

        % Button pushed function: FluffinessButton
        function FluffinessButtonPushed(app, event)
            if app.channels == 1
                surface_area = app.surface_area_1channel(app.imageStack);
                biovolume = app.biovolume_1channel(app.imageStack);
                app.LogTextArea.Value{end+1} = sprintf('The fluffiness (SA/BV) is        %i\n',surface_area/biovolume);
            elseif app.channels == 2
                [red_surface_area, green_surface_area, merged_surface_area] = app.surface_area_2channel(app.imageStack);
                [red_biovolume, green_biovolume, merged_biovolume] = app.biovolume_2channel(app.imageStack);
                app.LogTextArea.Value{end+1} = sprintf('The red channel fluffiness (SA/BV) is        %i\n',red_surface_area/red_biovolume);
                app.LogTextArea.Value{end+1} = sprintf('The green channel fluffiness (SA/BV) is        %i\n',green_surface_area/green_biovolume);
                app.LogTextArea.Value{end+1} = sprintf('The fluffiness (SA/BV) after collapsing red and green channels is        %i\n',merged_surface_area/merged_biovolume);
            end
        end

        % Button pushed function: ConnectivityButton
        function ConnectivityButtonPushed(app, event)
            if app.channels == 1
                biovolume = app.biovolume_1channel(app.imageStack);
                objects = app.objects_1channel(app.imageStack);
                app.LogTextArea.Value{end+1} = sprintf('The connectivity (Avg. BV of an object) is        %i\n',biovolume/objects);
            elseif app.channels == 2
                [red_biovolume, green_biovolume, merged_biovolume] = app.biovolume_2channel(app.imageStack);
                [red_objects,green_objects, merged_objects] = app.objects_2channel(app.imageStack);
                app.LogTextArea.Value{end+1} = sprintf('The red channel connectivity (Avg. BV of an object) is        %i\n',red_biovolume/red_objects);
                app.LogTextArea.Value{end+1} = sprintf('The green channel connectivity (Avg. BV of an object) is        %i\n',green_biovolume/green_objects);
                app.LogTextArea.Value{end+1} = sprintf('The connectivity (Avg. BV of an object) after collapsing red and green channels is        %i\n',merged_biovolume/merged_objects);
            end
        end

        % Button pushed function: ViabilityButton
        function ViabilityButtonPushed(app, event)
            if app.channels == 1
                app.LogTextArea.Value{end+1} = 'Viability cannot be calculated from 1 channel';
            elseif app.channels == 2
                [red_biovolume, green_biovolume, merged_biovolume] = app.biovolume_2channel(app.imageStack);
                app.LogTextArea.Value{end+1} = sprintf('The unweighted viability of the image stack is        %i\n',green_biovolume/(red_biovolume+green_biovolume));
            end

        end

        % Button pushed function: ConvexHullPorosityButton
        function ConvexHullPorosityButtonPushed(app, event)
            if app.channels == 1
                porosity = app.porosity_1channel(app.imageStack);
            elseif app.channels == 2
                [chporosityred, chporositygreen, chporositymerged] = app.porosity_2channel(app.imageStack);
            end
        end

        % Button pushed function: BiovolumeBatchButton
        function BiovolumeBatchButtonPushed(app, event)
            if app.channels == 1
                set(app.ExportCSVButton,'enable','on');
                app.UITable.Data = {'Image Stack';
                                    'Threshold';
                                    'Biovolume';
                                    };
                
                for i = 1:length(app.arrayNames)
                
                fields = fieldnames(app.dataStruct);
                
                method = [app.BEMButton.Value,app.OtsuButton.Value,app.IterativeSelectionButton.Value,app.ManualButton.Value];
                [greenThresh, vcolsgreen] = app.threshold_1channel(app.dataStruct.(fields{i}), method);
                app.green_threshold_value = greenThresh;
                
                green_biovolume = app.biovolume_1channel(app.dataStruct.(fields{i}));
                
                app.UITable.Data = horzcat(app.UITable.Data, { , fields{i};
                                                               , app.green_threshold_value;
                                                               , green_biovolume;
                                                               });
                                  
                end
            elseif app.channels == 2
                set(app.ExportCSVButton,'enable','on');
                app.UITable.Data = {'Image Stack';
                                    'Red channel threshold';
                                    'Green channel threshold';
                                    'Red channel biovolume';
                                    'Green channel biovolume';
                                    'Collapsed channel biovolume';
                                    };
                
                for i = 1:length(app.arrayNames)
                
                fields = fieldnames(app.dataStruct);
                
                method = [app.BEMButton.Value,app.OtsuButton.Value,app.IterativeSelectionButton.Value,app.ManualButton.Value];
                [redThresh, greenThresh, vcolsred, vcolsgreen] = app.threshold_2channel(app.dataStruct.(fields{i}), method);
                app.red_threshold_value = redThresh;
                app.green_threshold_value = greenThresh;
                
                [red_biovolume, green_biovolume, merged_biovolume] = app.biovolume_2channel(app.dataStruct.(fields{i}));
                
                app.UITable.Data = horzcat(app.UITable.Data, { , fields{i};
                                                               , app.red_threshold_value;
                                                               , app.green_threshold_value;
                                                               , red_biovolume;
                                                               , green_biovolume;
                                                               , merged_biovolume;
                                                               });
                                  
                end
            end
        end

        % Button pushed function: ofObjectsBatchButton
        function ofObjectsBatchButtonPushed(app, event)
            set(app.ExportCSVButton,'enable','on');
            if app.channels == 1
                app.UITable.Data = {'Image Stack';
                                    'Threshold';
                                    'Total number of objects';
                                    };
                
                for i = 1:length(app.arrayNames)
                
                fields = fieldnames(app.dataStruct);
                
                method = [app.BEMButton.Value,app.OtsuButton.Value,app.IterativeSelectionButton.Value,app.ManualButton.Value];
                [greenThresh, vcolsgreen] = app.threshold_1channel(app.dataStruct.(fields{i}), method);
                app.green_threshold_value = greenThresh;
                
                green_objects = app.objects_1channel(app.dataStruct.(fields{i}));
                
                app.UITable.Data = horzcat(app.UITable.Data, { , fields{i};
                                                               , app.green_threshold_value;
                                                               , green_objects;
                                                               });
                                  
                end            
            elseif app.channels == 2
                app.UITable.Data = {'Image Stack';
                                    'Red channel threshold';
                                    'Green channel threshold';
                                    'Red channel total number of objects';
                                    'Green channel total number of objects';
                                    'Collapsed channel number of objects';
                                    };
                
                for i = 1:length(app.arrayNames)
                
                fields = fieldnames(app.dataStruct);
                
                method = [app.BEMButton.Value,app.OtsuButton.Value,app.IterativeSelectionButton.Value,app.ManualButton.Value];
                [redThresh, greenThresh, vcolsred, vcolsgreen] = app.threshold_2channel(app.dataStruct.(fields{i}), method);
                app.red_threshold_value = redThresh;
                app.green_threshold_value = greenThresh;
                
                [red_objects,green_objects,merged_objects] = app.objects_2channel(app.dataStruct.(fields{i}));
                
                app.UITable.Data = horzcat(app.UITable.Data, { , fields{i};
                                                               , app.red_threshold_value;
                                                               , app.green_threshold_value;
                                                               , red_objects;
                                                               , green_objects;
                                                               , merged_objects;
                                                               });
                                  
                end
            end
        end

        % Button pushed function: SurfaceAreaBatchButton
        function SurfaceAreaBatchButtonPushed(app, event)
            set(app.ExportCSVButton,'enable','on');
            if app.channels == 1
                app.UITable.Data = {'Image Stack';
                                    'Threshold';
                                    'Exposed voxel surfaces';
                                    };
                
                for i = 1:length(app.arrayNames)
                
                fields = fieldnames(app.dataStruct);
                
                method = [app.BEMButton.Value,app.OtsuButton.Value,app.IterativeSelectionButton.Value,app.ManualButton.Value];
                [greenThresh, vcolsgreen] = app.threshold_1channel(app.dataStruct.(fields{i}), method);
                app.green_threshold_value = greenThresh;
                
                green_surface_area = app.surface_area_1channel(app.dataStruct.(fields{i}));
                
                app.UITable.Data = horzcat(app.UITable.Data, { , fields{i};
                                                               , app.green_threshold_value;
                                                               , green_surface_area;
                                                               });
                                  
                end    
            elseif app.channels == 2
                app.UITable.Data = {'Image Stack';
                                    'Red channel threshold';
                                    'Green channel threshold';
                                    'Red channel exposed voxel surfaces';
                                    'Green channel exposed voxel surfaces';
                                    'Collapsed channel exposed voxel surfaces';
                                    };
                
                for i = 1:length(app.arrayNames)
                
                fields = fieldnames(app.dataStruct);
                
                method = [app.BEMButton.Value,app.OtsuButton.Value,app.IterativeSelectionButton.Value,app.ManualButton.Value];
                [redThresh, greenThresh, vcolsred, vcolsgreen] = app.threshold_2channel(app.dataStruct.(fields{i}), method);
                app.red_threshold_value = redThresh;
                app.green_threshold_value = greenThresh;
                
                [red_surface_area,green_surface_area,merged_surface_area] = app.surface_area_2channel(app.dataStruct.(fields{i}));
                
                app.UITable.Data = horzcat(app.UITable.Data, { , fields{i};
                                                               , app.red_threshold_value;
                                                               , app.green_threshold_value;
                                                               , red_surface_area;
                                                               , green_surface_area;
                                                               , merged_surface_area;
                                                               });
                                  
                end    
            end
        end

        % Button pushed function: FluffinessBatchButton
        function FluffinessBatchButtonPushed(app, event)
            set(app.ExportCSVButton,'enable','on');
            if app.channels == 1
                app.UITable.Data = {'Image Stack';
                                    'Threshold';
                                    'Fluffiness (SA/BV)';
                                    };
                
                for i = 1:length(app.arrayNames)
                
                fields = fieldnames(app.dataStruct);
                
                method = [app.BEMButton.Value,app.OtsuButton.Value,app.IterativeSelectionButton.Value,app.ManualButton.Value];
                [greenThresh, vcolsgreen] = app.threshold_1channel(app.dataStruct.(fields{i}), method);
                app.green_threshold_value = greenThresh;
                
                green_biovolume = app.biovolume_1channel(app.dataStruct.(fields{i}));
                green_surface_area = app.surface_area_1channel(app.dataStruct.(fields{i}));
                
                app.UITable.Data = horzcat(app.UITable.Data, { , fields{i};
                                                               , app.green_threshold_value;
                                                               , green_surface_area/green_biovolume;
                                                               });
                                  
                end              
            elseif app.channels == 2
                app.UITable.Data = {'Image Stack';
                                    'Red channel threshold';
                                    'Green channel threshold';
                                    'Red channel fluffiness (SA/BV)';
                                    'Green channel fluffiness (SA/BV)';
                                    'Collapsed channel fluffiness (SA/BV)';
                                    };
                
                for i = 1:length(app.arrayNames)
                
                fields = fieldnames(app.dataStruct);
                
                method = [app.BEMButton.Value,app.OtsuButton.Value,app.IterativeSelectionButton.Value,app.ManualButton.Value];
                [redThresh, greenThresh, vcolsred, vcolsgreen] = app.threshold_2channel(app.dataStruct.(fields{i}), method);
                app.red_threshold_value = redThresh;
                app.green_threshold_value = greenThresh;
                
                [red_biovolume, green_biovolume, merged_biovolume] = app.biovolume_2channel(app.dataStruct.(fields{i}));
                [red_surface_area, green_surface_area, merged_surface_area] = app.surface_area_2channel(app.dataStruct.(fields{i}));
                
                app.UITable.Data = horzcat(app.UITable.Data, { , fields{i};
                                                               , app.red_threshold_value;
                                                               , app.green_threshold_value;
                                                               , red_surface_area/red_biovolume;
                                                               , green_surface_area/green_biovolume;
                                                               , merged_surface_area/merged_biovolume;
                                                               });
                                  
                end         
            end
        end

        % Button pushed function: ConnectivityBatchButton
        function ConnectivityBatchButtonPushed(app, event)
            set(app.ExportCSVButton,'enable','on');
            if app.channels == 1
                app.UITable.Data = {'Image Stack';
                                    'Threshold';
                                    'Connectivity (Avg. BV per object)';
                                    };
                
                for i = 1:length(app.arrayNames)
                
                fields = fieldnames(app.dataStruct);
                
                method = [app.BEMButton.Value,app.OtsuButton.Value,app.IterativeSelectionButton.Value,app.ManualButton.Value];
                [greenThresh, vcolsgreen] = app.threshold_1channel(app.dataStruct.(fields{i}), method);
                app.green_threshold_value = greenThresh;
                
                green_biovolume = app.biovolume_1channel(app.dataStruct.(fields{i}));
                green_objects = app.objects_1channel(app.dataStruct.(fields{i}));
                
                app.UITable.Data = horzcat(app.UITable.Data, { , fields{i};
                                                               , app.green_threshold_value;
                                                               , green_biovolume/green_objects;
                                                               });
                                  
                end   
            elseif app.channels == 2
                app.UITable.Data = {'Image Stack';
                                    'Red channel threshold';
                                    'Green channel threshold';
                                    'Red channel connectivity (Avg. BV per object)';
                                    'Green channel connectivity (Avg. BV per object)';
                                    'Collapsed channel connectivity (Avg. BV per object)';
                                    };
                
                for i = 1:length(app.arrayNames)
                
                fields = fieldnames(app.dataStruct);
                
                method = [app.BEMButton.Value,app.OtsuButton.Value,app.IterativeSelectionButton.Value,app.ManualButton.Value];
                [redThresh, greenThresh, vcolsred, vcolsgreen] = app.threshold_2channel(app.dataStruct.(fields{i}), method);
                app.red_threshold_value = redThresh;
                app.green_threshold_value = greenThresh;
                
                [red_biovolume, green_biovolume,merged_biovolume] = app.biovolume_2channel(app.dataStruct.(fields{i}));
                [red_objects,green_objects,merged_objects] = app.objects_2channel(app.dataStruct.(fields{i}));
                
                app.UITable.Data = horzcat(app.UITable.Data, { , fields{i};
                                                               , app.red_threshold_value;
                                                               , app.green_threshold_value;
                                                               , red_biovolume/red_objects;
                                                               , green_biovolume/green_objects;
                                                               , merged_biovolume/merged_objects;
                                                               });
                                  
                end   
            end
        end

        % Button pushed function: CHPorosityBatchButton
        function CHPorosityBatchButtonPushed(app, event)
            set(app.ExportCSVButton,'enable','on');
            if app.channels == 1
                app.UITable.Data = {'Image Stack';
                                    'Threshold';
                                    'Convex hull porosity';
                                    };
                
                for i = 1:length(app.arrayNames)
                
                fields = fieldnames(app.dataStruct);
                
                method = [app.BEMButton.Value,app.OtsuButton.Value,app.IterativeSelectionButton.Value,app.ManualButton.Value];
                [greenThresh, vcolsgreen] = app.threshold_1channel(app.dataStruct.(fields{i}), method);
                app.green_threshold_value = greenThresh;
                
                chporositygreen = app.porosity_1channel(app.dataStruct.(fields{i}));
                
                app.UITable.Data = horzcat(app.UITable.Data, { , fields{i};
                                                               , app.green_threshold_value;
                                                               , chporositygreen;
                                                               });
                                  
                end              
            elseif app.channels == 2
                app.UITable.Data = {'Image Stack';
                                    'Red channel threshold';
                                    'Green channel threshold';
                                    'Red channel convex hull porosity';
                                    'Green channel convex hull porosity';
                                    'Collapsed channel convex hull porosity';
                                    };
                
                for i = 1:length(app.arrayNames)
                
                fields = fieldnames(app.dataStruct);
                
                method = [app.BEMButton.Value,app.OtsuButton.Value,app.IterativeSelectionButton.Value,app.ManualButton.Value];
                [redThresh, greenThresh, vcolsred, vcolsgreen] = app.threshold_2channel(app.dataStruct.(fields{i}), method);
                app.red_threshold_value = redThresh;
                app.green_threshold_value = greenThresh;
                
                [chporosityred,chporositygreen,chporositymerged] = app.porosity_2channel(app.dataStruct.(fields{i}));
                
                app.UITable.Data = horzcat(app.UITable.Data, { , fields{i};
                                                               , app.red_threshold_value;
                                                               , app.green_threshold_value;
                                                               , chporosityred;
                                                               , chporositygreen;
                                                               , chporositymerged;
                                                               });
                                  
                end  
            end
        end

        % Button pushed function: ViabilityBatchButton
        function ViabilityBatchButtonPushed(app, event)
            if app.channels == 1
                app.LogTextArea.Value{end+1} = 'Viability cannot be calculated from 1 channel';
            elseif app.channels == 2
                set(app.ExportCSVButton,'enable','on');
                app.UITable.Data = {'Image Stack';
                                    'Red channel threshold';
                                    'Green channel threshold';
                                    'Viability (Proportion of voxels that are green)';
                                    };
                
                for i = 1:length(app.arrayNames)
                
                fields = fieldnames(app.dataStruct);
                
                method = [app.BEMButton.Value,app.OtsuButton.Value,app.IterativeSelectionButton.Value,app.ManualButton.Value];
                [redThresh, greenThresh, vcolsred, vcolsgreen] = app.threshold_2channel(app.dataStruct.(fields{i}), method);
                app.red_threshold_value = redThresh;
                app.green_threshold_value = greenThresh;
                
                [red_biovolume, green_biovolume] = app.biovolume_2channel(app.dataStruct.(fields{i}));
                
                app.UITable.Data = horzcat(app.UITable.Data, { , fields{i};
                                                               , app.red_threshold_value;
                                                               , app.green_threshold_value;
                                                               , green_biovolume/(red_biovolume+green_biovolume);
                                                               });
                                  
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BiofilmArchitectureInferenceToolUIFigure = uifigure('Visible', 'off');
            app.BiofilmArchitectureInferenceToolUIFigure.Name = 'Biofilm Architecture Inference Tool';
            app.UIAxes = uiaxes(app.BiofilmArchitectureInferenceToolUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.BiofilmArchitectureInferenceToolUIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.SelectFileButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.ImageStackDropDownLabel = uilabel(app.BiofilmArchitectureInferenceToolUIFigure);
            app.ImageStackDropDownLabel.Text = 'Image Stack';
            app.ImageStackDropDown = uidropdown(app.BiofilmArchitectureInferenceToolUIFigure);
            app.ImageStackDropDown.Items = {'No Selection', 'Option 2', 'Option 3', 'Option 4'};
            app.ImageStackDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageStackDropDownValueChanged, true);
            app.ImageStackDropDown.Value = 'No Selection';
            app.LogTextAreaLabel = uilabel(app.BiofilmArchitectureInferenceToolUIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.BiofilmArchitectureInferenceToolUIFigure);
            app.LogTextArea.Editable = 'off';
            app.ThresholdingButtonGroup = uibuttongroup(app.BiofilmArchitectureInferenceToolUIFigure);
            app.ThresholdingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThresholdingButtonGroupSelectionChanged, true);
            app.ThresholdingButtonGroup.Title = 'Thresholding';
            app.BEMButton = uiradiobutton(app.ThresholdingButtonGroup);
            app.BEMButton.Text = 'BEM';
            app.BEMButton.Value = true;
            app.OtsuButton = uiradiobutton(app.ThresholdingButtonGroup);
            app.OtsuButton.Text = 'Otsu';
            app.IterativeSelectionButton = uiradiobutton(app.ThresholdingButtonGroup);
            app.IterativeSelectionButton.Text = 'Iterative Selection';
            app.ThresholdButton = uibutton(app.ThresholdingButtonGroup, 'push');
            app.ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdButtonPushed, true);
            app.ThresholdButton.Enable = 'off';
            app.ThresholdButton.Text = 'Threshold';
            app.ManualButton = uiradiobutton(app.ThresholdingButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.RedChannelEditFieldLabel = uilabel(app.ThresholdingButtonGroup);
            app.RedChannelEditFieldLabel.Enable = 'off';
            app.RedChannelEditFieldLabel.Text = 'Red Channel';
            app.RedChannelEditField = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.RedChannelEditField.Limits = [0 255];
            app.RedChannelEditField.RoundFractionalValues = 'on';
            app.RedChannelEditField.ValueDisplayFormat = '%.0f';
            app.RedChannelEditField.Enable = 'off';
            app.RedChannelEditField.Value = 30;
            app.GreenChannelEditFieldLabel = uilabel(app.ThresholdingButtonGroup);
            app.GreenChannelEditFieldLabel.Enable = 'off';
            app.GreenChannelEditFieldLabel.Text = 'Green Channel';
            app.GreenChannelEditField = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.GreenChannelEditField.Limits = [0 255];
            app.GreenChannelEditField.RoundFractionalValues = 'on';
            app.GreenChannelEditField.ValueDisplayFormat = '%.0f';
            app.GreenChannelEditField.Enable = 'off';
            app.GreenChannelEditField.Value = 30;
            app.UITable = uitable(app.BiofilmArchitectureInferenceToolUIFigure);
            app.UITable.ColumnName = {'Name'; 'Value'};
            app.UITable.RowName = {};
            app.BiovolumeButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.BiovolumeButton.ButtonPushedFcn = createCallbackFcn(app, @BiovolumeButtonPushed, true);
            app.BiovolumeButton.Enable = 'off';
            app.BiovolumeButton.Text = 'Biovolume';
            app.ExportCSVButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.ExportCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCSVButtonPushed, true);
            app.ExportCSVButton.Enable = 'off';
            app.ExportCSVButton.Text = 'Export CSV';
            app.SurfaceAreaButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.SurfaceAreaButton.ButtonPushedFcn = createCallbackFcn(app, @SurfaceAreaButtonPushed, true);
            app.SurfaceAreaButton.Enable = 'off';
            app.SurfaceAreaButton.Text = 'Surface Area';
            app.ofObjectsButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.ofObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @ofObjectsButtonPushed, true);
            app.ofObjectsButton.Enable = 'off';
            app.ofObjectsButton.Text = '# of Objects';
            app.AnalyzeAllButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.AnalyzeAllButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeAllButtonPushed, true);
            app.AnalyzeAllButton.Enable = 'off';
            app.AnalyzeAllButton.Text = 'Analyze All';
            app.CalculateSelectedStackLabel = uilabel(app.BiofilmArchitectureInferenceToolUIFigure);
            app.CalculateSelectedStackLabel.Text = 'Calculate Selected Stack';
            app.FiguresLabel = uilabel(app.BiofilmArchitectureInferenceToolUIFigure);
            app.FiguresLabel.Text = 'Figures';
            app.LogHistogramButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.LogHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @LogHistogramButtonPushed, true);
            app.LogHistogramButton.Enable = 'off';
            app.LogHistogramButton.Text = 'Log Histogram';
            app.BiovolumebyThresholdButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.BiovolumebyThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @BiovolumebyThresholdButtonPushed, true);
            app.BiovolumebyThresholdButton.Enable = 'off';
            app.BiovolumebyThresholdButton.Text = 'Biovolume by Threshold';
            app.FluffinessButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.FluffinessButton.ButtonPushedFcn = createCallbackFcn(app, @FluffinessButtonPushed, true);
            app.FluffinessButton.Enable = 'off';
            app.FluffinessButton.Text = 'Fluffiness';
            app.ConnectivityButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.ConnectivityButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectivityButtonPushed, true);
            app.ConnectivityButton.Enable = 'off';
            app.ConnectivityButton.Text = 'Connectivity';
            app.ConvexHullPorosityButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.ConvexHullPorosityButton.ButtonPushedFcn = createCallbackFcn(app, @ConvexHullPorosityButtonPushed, true);
            app.ConvexHullPorosityButton.Enable = 'off';
            app.ConvexHullPorosityButton.Text = 'Convex Hull Porosity';
            app.ViabilityButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.ViabilityButton.ButtonPushedFcn = createCallbackFcn(app, @ViabilityButtonPushed, true);
            app.ViabilityButton.Enable = 'off';
            app.ViabilityButton.Text = 'Viability';
            app.BiovolumeBatchButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.BiovolumeBatchButton.ButtonPushedFcn = createCallbackFcn(app, @BiovolumeBatchButtonPushed, true);
            app.BiovolumeBatchButton.Enable = 'off';
            app.BiovolumeBatchButton.Text = 'Biovolume Batch';
            app.ofObjectsBatchButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.ofObjectsBatchButton.ButtonPushedFcn = createCallbackFcn(app, @ofObjectsBatchButtonPushed, true);
            app.ofObjectsBatchButton.Enable = 'off';
            app.ofObjectsBatchButton.Text = '# of Objects Batch';
            app.SurfaceAreaBatchButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.SurfaceAreaBatchButton.ButtonPushedFcn = createCallbackFcn(app, @SurfaceAreaBatchButtonPushed, true);
            app.SurfaceAreaBatchButton.Enable = 'off';
            app.SurfaceAreaBatchButton.Text = 'Surface Area Batch';
            app.FluffinessBatchButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.FluffinessBatchButton.ButtonPushedFcn = createCallbackFcn(app, @FluffinessBatchButtonPushed, true);
            app.FluffinessBatchButton.Enable = 'off';
            app.FluffinessBatchButton.Text = 'Fluffiness Batch';
            app.ConnectivityBatchButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.ConnectivityBatchButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectivityBatchButtonPushed, true);
            app.ConnectivityBatchButton.Enable = 'off';
            app.ConnectivityBatchButton.Text = 'Connectivity Batch';
            app.CHPorosityBatchButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.CHPorosityBatchButton.ButtonPushedFcn = createCallbackFcn(app, @CHPorosityBatchButtonPushed, true);
            app.CHPorosityBatchButton.Enable = 'off';
            app.CHPorosityBatchButton.Text = 'CH Porosity Batch';
            app.ViabilityBatchButton = uibutton(app.BiofilmArchitectureInferenceToolUIFigure, 'push');
            app.ViabilityBatchButton.ButtonPushedFcn = createCallbackFcn(app, @ViabilityBatchButtonPushed, true);
            app.ViabilityBatchButton.Enable = 'off';
            app.ViabilityBatchButton.Text = 'Viability Batch';
            % Show the figure after all components are created
            app.BiofilmArchitectureInferenceToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BAIT_1_1_2022a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BiofilmArchitectureInferenceToolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BiofilmArchitectureInferenceToolUIFigure)
        end
    end
end